classdef UnoGameClient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Background          matlab.ui.control.Image
        PlayerCard1         matlab.ui.control.Image
        PlayerCard2         matlab.ui.control.Image
        PlayerCard3         matlab.ui.control.Image
        PlayerCard4         matlab.ui.control.Image
        PlayerCard5         matlab.ui.control.Image
        PlayerCard6         matlab.ui.control.Image
        PlayerCard7         matlab.ui.control.Image
        nextButton          matlab.ui.control.Button
        prevButton          matlab.ui.control.Button
        OpponentCard7       matlab.ui.control.Image
        OpponentCard6       matlab.ui.control.Image
        OpponentCard1       matlab.ui.control.Image
        OpponentCard5       matlab.ui.control.Image
        OpponentCard2       matlab.ui.control.Image
        OpponentCard4       matlab.ui.control.Image
        OpponentCard3       matlab.ui.control.Image
        OpponentCardsLabel  matlab.ui.control.Label
        OpponentCardsValue  matlab.ui.control.Label
        MenuButton          matlab.ui.control.Button
        DiscardPileImage    matlab.ui.control.Image
        DrawDeck            matlab.ui.control.Image
        DrawDeckLabel       matlab.ui.control.Label
        DiscardPileLabel    matlab.ui.control.Label
        UnoButton           matlab.ui.control.Image
        UnoButtonLabel      matlab.ui.control.Label
        TurnLabel           matlab.ui.control.Label
        SplashScreen        matlab.ui.control.Image
    end

    
    properties (Access = public)
        PlayerHandStatus = 0; % To check which set of player cards we want to display
        PlayerHand; % Struct array that keep strack of the player hand
        Turn = 0; % Stores if it's player 1 or player 2's turn
        ValidUno = 0; % Checks for Valid Uno
        OpponentHandNum; % Stores number of cards in opponent's hand
        DiscardPileCard; % Stores the discard pile
        deckChoice = "_"; % Choice of deck (standard vs minimalist)
        home_screen_app; % Home Screen Menu App
    end
    
    properties (Access = private)
        ChannelID = 1253923; %Thingspeak Channel IDs + API Keys
        WriteAPIKey = 'AXU8658453CFLG03';
        ReadAPIKey = 'P8REAMX0A7W127U9';
    end
    
    methods (Access = public)
        
        function displayPlayerCards(app, i) % Displays Player Cards similar to UnoGame.mlapp
            if(app.Turn == 1)
                app.TurnLabel.Text = {'YOUR TURN'; 'PLAY'; 'NOW'};
            else
                app.TurnLabel.Text = {'PLEASE WAIT'; 'FOR YOUR'; 'TURN'};
            end
            
            if((isempty(app.PlayerHand)))
                app.SplashScreen.Visible = 'on';
                app.SplashScreen.ImageSource = 'WinnerScreen.gif';
                [y, Fs] = audioread('victory.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
            end
            
            if(i <= length(app.PlayerHand)) % Display the 'i'-th Card only if there are actually a minimum of i Cards in the Player Hand
                app.PlayerCard1.Visible = 'on';
                if(app.PlayerHand(i).number == 13) % Display Generic Wild Card
                    app.PlayerCard1.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i).number == 14) % Display Generic Wild +4 Card
                    app.PlayerCard1.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else % Displays Color Cards (Number + Action Cards)
                    app.PlayerCard1.ImageSource = char(app.PlayerHand(i).color + app.deckChoice + app.PlayerHand(i).number + ".png");
                end
            else
                app.PlayerCard1.Visible = 'off'; % The 'i'-th Card doesn't exist in the deck, so don't display it
            end
            
            if(i+1 <=length(app.PlayerHand))
                app.PlayerCard2.Visible = 'on';
                if(app.PlayerHand(i+1).number == 13)
                    app.PlayerCard2.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i+1).number == 14)
                    app.PlayerCard2.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else
                    app.PlayerCard2.ImageSource = char(app.PlayerHand(i+1).color + app.deckChoice + app.PlayerHand(i+1).number + ".png");
                end
            else
                app.PlayerCard2.Visible = 'off';
            end
            
            if(i+2 <= length(app.PlayerHand))
                app.PlayerCard3.Visible = 'on';
                if(app.PlayerHand(i+2).number == 13)
                    app.PlayerCard3.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i+2).number == 14)
                    app.PlayerCard3.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else
                    app.PlayerCard3.ImageSource = char(app.PlayerHand(i+2).color + app.deckChoice + app.PlayerHand(i+2).number + ".png");
                end
            else
                app.PlayerCard3.Visible = 'off';
            end
            
            if(i+3 <= length(app.PlayerHand))
                app.PlayerCard4.Visible = 'on';
                if(app.PlayerHand(i+3).number == 13)
                    app.PlayerCard4.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i+3).number == 14)
                    app.PlayerCard4.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else
                    app.PlayerCard4.ImageSource = char(app.PlayerHand(i+3).color + app.deckChoice + app.PlayerHand(i+3).number + ".png");
                end
            else
                app.PlayerCard4.Visible = 'off';
            end
            
            if(i+4 <= length(app.PlayerHand))
                app.PlayerCard5.Visible = 'on';
                if(app.PlayerHand(i+4).number == 13)
                    app.PlayerCard5.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i+4).number == 14)
                    app.PlayerCard5.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else
                    app.PlayerCard5.ImageSource = char(app.PlayerHand(i+4).color + app.deckChoice + app.PlayerHand(i+4).number + ".png");
                end
            else
                app.PlayerCard5.Visible = 'off';
            end
            
            if(i+5 <= length(app.PlayerHand))
                app.PlayerCard6.Visible = 'on';
                if(app.PlayerHand(i+5).number == 13)
                    app.PlayerCard6.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i+5).number == 14)
                    app.PlayerCard6.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else
                    app.PlayerCard6.ImageSource = char(app.PlayerHand(i+5).color + app.deckChoice + app.PlayerHand(i+5).number + ".png");
                end
            else
                app.PlayerCard6.Visible = 'off';
            end
            
            if(i+6 <= length(app.PlayerHand))
                app.PlayerCard7.Visible = 'on';
                if(app.PlayerHand(i+6).number == 13)
                    app.PlayerCard7.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i+6).number == 14)
                    app.PlayerCard7.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else
                    app.PlayerCard7.ImageSource = char(app.PlayerHand(i+6).color + app.deckChoice + app.PlayerHand(i+6).number + ".png");
                end
            else
                app.PlayerCard7.Visible = 'off';
            end
        end
        
        function displayOpponentCards(app) % Displays Opponent Cards similar to UnoGame.mlapp
            % Instead of using length(app.UnoGameClient.PlayerHand) we use
            % length(app.MainUnoGame.PlayerHand) since app.MainUnoGame
            % refers to Player 1, who is the 'opponent' here.
            if(app.Turn == 1)
                app.TurnLabel.Text = {'YOUR TURN'; 'PLAY'; 'NOW'};
            else
                app.TurnLabel.Text = {'PLEASE WAIT'; 'FOR YOUR'; 'TURN'};
            end
            
            app.OpponentCardsValue.Text = num2str(app.OpponentHandNum);
            
            app.OpponentCard1.ImageSource = char('Deck' + app.deckChoice + ".png");
            app.OpponentCard2.ImageSource = char('Deck' + app.deckChoice + ".png");
            app.OpponentCard3.ImageSource = char('Deck' + app.deckChoice + ".png");
            app.OpponentCard4.ImageSource = char('Deck' + app.deckChoice + ".png");
            app.OpponentCard5.ImageSource = char('Deck' + app.deckChoice + ".png");
            app.OpponentCard6.ImageSource = char('Deck' + app.deckChoice + ".png");
            app.OpponentCard7.ImageSource = char('Deck' + app.deckChoice + ".png");
            
            switch(app.OpponentHandNum)
                case 0
                    app.SplashScreen.Visible = 'on';
                    app.SplashScreen.ImageSource = 'Loser.png';
                    [y, Fs] = audioread('loser.wav');   % reads the audio
                    sound(y,Fs);    % plays the audio
                case 1
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'off';
                    app.OpponentCard3.Visible = 'off';
                    app.OpponentCard4.Visible = 'off';
                    app.OpponentCard5.Visible = 'off';
                    app.OpponentCard6.Visible = 'off';
                    app.OpponentCard7.Visible = 'off';
                case 2
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'on';
                    app.OpponentCard3.Visible = 'off';
                    app.OpponentCard4.Visible = 'off';
                    app.OpponentCard5.Visible = 'off';
                    app.OpponentCard6.Visible = 'off';
                    app.OpponentCard7.Visible = 'off';
                case 3
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'on';
                    app.OpponentCard3.Visible = 'on';
                    app.OpponentCard4.Visible = 'off';
                    app.OpponentCard5.Visible = 'off';
                    app.OpponentCard6.Visible = 'off';
                    app.OpponentCard7.Visible = 'off';
                case 4
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'on';
                    app.OpponentCard3.Visible = 'on';
                    app.OpponentCard4.Visible = 'on';
                    app.OpponentCard5.Visible = 'off';
                    app.OpponentCard6.Visible = 'off';
                    app.OpponentCard7.Visible = 'off';
                case 5
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'on';
                    app.OpponentCard3.Visible = 'on';
                    app.OpponentCard4.Visible = 'on';
                    app.OpponentCard5.Visible = 'on';
                    app.OpponentCard6.Visible = 'off';
                    app.OpponentCard7.Visible = 'off';
                case 6
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'on';
                    app.OpponentCard3.Visible = 'on';
                    app.OpponentCard4.Visible = 'on';
                    app.OpponentCard5.Visible = 'on';
                    app.OpponentCard6.Visible = 'on';
                    app.OpponentCard7.Visible = 'off';
                otherwise
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'on';
                    app.OpponentCard3.Visible = 'on';
                    app.OpponentCard4.Visible = 'on';
                    app.OpponentCard5.Visible = 'on';
                    app.OpponentCard6.Visible = 'on';
                    app.OpponentCard7.Visible = 'on';
            end
        end
        
        % Displays the discard pile similar to UnoGame.mlapp
        function displayDiscardPile(app)
            if(app.Turn == 1)
                app.TurnLabel.Text = {'YOUR TURN'; 'PLAY'; 'NOW'};
            else
                app.TurnLabel.Text = {'PLEASE WAIT'; 'FOR YOUR'; 'TURN'};
            end
            
            app.DiscardPileImage.ImageSource = char(app.DiscardPileCard.color + app.deckChoice + app.DiscardPileCard.number + ".png");
        end
        
        % Determines if we can Play a Selected Card and if yes, how to go about it
        function playCard(app, i)
            thingspeakData = thingSpeakRead(app.ChannelID, 'OutputFormat','table', 'ReadKey', app.ReadAPIKey);
            
            cardsString = string(thingspeakData.DiscardPile);
            card = split(cardsString, "_");
            newDiscardPileCard = struct('color',{card(1)},'number',{str2num(card(2))},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
            app.DiscardPileCard = newDiscardPileCard;
            displayDiscardPile(app);
            
            if(strcmp(app.PlayerHand(i).color, app.DiscardPileCard.color) || (app.PlayerHand(i).number == app.DiscardPileCard.number) || (app.PlayerHand(i).number > 12))
                % The WildChoice App changes the color of the Wild/Wild +
                % 4 Cards for display purposes, but we want to revert it
                % back to the default "Red" to ensure that we don't exceed
                % the deck size.
                if(app.DiscardPileCard.number >= 13)
                    app.DiscardPileCard.color = "Red";
                end
                
                app.DiscardPileCard = app.PlayerHand(i); % Change the discardPile to the card being played
                displayDiscardPile(app);
                
                % Player 1 loses and Player 2 wins.
                if((isempty(app.PlayerHand)))
                    app.SplashScreen.Visible = 'on';
                    app.SplashScreen.ImageSource = 'WinnerScreen.gif';
                    [y, Fs] = audioread('victory.wav');   % reads the audio
                    sound(y,Fs);    % plays the audio
                end
                
                if(app.ValidUno ~= 1) % We don't want to modify the Player 1's Uno Status
                    app.ValidUno = 0;
                end
                
                if(app.DiscardPileCard.number == 13) % If the card played is a Wild Card, prompt user for color choice
                    WildChoice(app);
                elseif(app.DiscardPileCard.number == 14) % If the card played is a Wild +4 Card, prompt user for color choice and add 4 cards to Player 2's hand
                    WildChoice(app);
                else
                    displayDiscardPile(app); % Updates the Discard Pile Display
                end
                
                app.PlayerHand(i) = []; % Delete the card from the Player's Hand
                displayOpponentCards(app);
                displayPlayerCards(app, (1 + app.PlayerHandStatus*7));
                
                playerCards = "";
                for i=1:length(app.PlayerHand)
                    colorChar = char(app.PlayerHand(i).color);
                    playerCards = playerCards + colorChar(1) + app.PlayerHand(i).number + "_";
                end
                
                %Writing updated data to the thingspeak channel
                pause(1);
                thingSpeakWrite(app.ChannelID, {thingspeakData.Player1CardsNum, char(playerCards), char(app.DiscardPileCard.color + "_" + app.DiscardPileCard.number), 0, thingspeakData.ValidUno, app.Turn, 1},'WriteKey',app.WriteAPIKey);
                
                %Syncs Game
                syncGame(app);
            end
        end
        
        %Syncs game data so that GUIs and info is automatically updated
        function syncGame(app)
            thingspeakData = thingSpeakRead(app.ChannelID, 'OutputFormat','table', 'ReadKey', app.ReadAPIKey);
            
            app.Turn = thingspeakData.Turn;
            
            %Shows player if it is their turn or not
            if(app.Turn == 1)
                app.TurnLabel.Text = {'YOUR TURN'; 'PLAY'; 'NOW'};
            else
                app.TurnLabel.Text = {'PLEASE WAIT'; 'FOR YOUR'; 'TURN'};
            end
            
            % Player 2 has lost
            if(thingspeakData.Player1CardsNum == 0)
                app.SplashScreen.Visible = 'on';
                app.SplashScreen.ImageSource = 'Loser.png';
                [y, Fs] = audioread('loser.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
            end
            
            if(app.Turn == 0)
                %Infinite Loop that keep running until it is player 2's
                %turn
                while(app.Turn ~= 1)
                    
                    if(strcmp(app.SplashScreen.Visible, 'on'))
                        break;
                    end
                    
                    pause(1);
                    thingspeakData = thingSpeakRead(app.ChannelID, 'OutputFormat','table', 'ReadKey', app.ReadAPIKey);
                    
                    % Updates Opponent Hand Number
                    app.OpponentHandNum = thingspeakData.Player1CardsNum;
                    displayOpponentCards(app);
                    
                    % Updates the Player Hand
                    app.PlayerHand = string2cards(app, string(thingspeakData.Player2Cards));
                    displayPlayerCards(app, (1 + app.PlayerHandStatus*7));
                    
                    % Updates Discard Pile
                    cardsString = string(thingspeakData.DiscardPile);
                    card = split(cardsString, "_");
                    app.DiscardPileCard = struct('color',{card(1)},'number',{str2num(card(2))},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                    displayDiscardPile(app);
                    
                    % Updates Turn
                    app.Turn = thingspeakData.Turn;
                end
            else
                while(app.OpponentHandNum == thingspeakData.Player1CardsNum)
                    
                    if(strcmp(app.SplashScreen.Visible, 'on'))
                        break;
                    end
                    
                    pause(1);
                    thingspeakData = thingSpeakRead(app.ChannelID, 'OutputFormat','table', 'ReadKey', app.ReadAPIKey);
                    
                    app.OpponentHandNum = thingspeakData.Player1CardsNum;
                    displayOpponentCards(app);
                    
                    app.PlayerHand = string2cards(app, string(thingspeakData.Player2Cards));
                    
                    displayPlayerCards(app, (1 + app.PlayerHandStatus*7));
                    
                    cardsString = string(thingspeakData.DiscardPile);
                    card = split(cardsString, "_");
                    app.DiscardPileCard = struct('color',{card(1)},'number',{str2num(card(2))},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                    displayDiscardPile(app);
                    
                    app.Turn = thingspeakData.Turn;
                end
            end
            app.OpponentHandNum = thingspeakData.Player1CardsNum;
            displayOpponentCards(app);
        end
        
        % Converts thingspeak string to the card struct
        function results = string2cards(app, cardsString)
            
            results = [];
            card = split(cardsString, "_");
            card(end) = [];
            for i=1:length(card)
                if(startsWith(card(i), "B"))
                    s = sscanf(card(i), 'B%d');
                    s = struct('color',{"Blue"},'number',{s},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                    results = [results s];
                elseif(startsWith(card(i),"R"))
                    s = sscanf(card(i), 'R%d');
                    s = struct('color',{"Red"},'number',{s},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                    results = [results s];
                elseif(startsWith(card(i),"Y"))
                    s = sscanf(card(i), 'Y%d');
                    s = struct('color',{"Yellow"},'number',{s},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                    results = [results s];
                elseif(startsWith(card(i),"G"))
                    s = sscanf(card(i), 'G%d');
                    s = struct('color',{"Green"},'number',{s},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                    results = [results s];
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, home_screen_client)
            app.home_screen_app = home_screen_client;
            app.SplashScreen.Visible = 'off'; % Turn Splash Screen Off
            app.OpponentHandNum = thingSpeakRead(app.ChannelID,'Fields',[1], 'ReadKey', app.ReadAPIKey);
            
            % Initializes player hand
            cardsStringTable = thingSpeakRead(app.ChannelID,'Fields',[2], 'OutputFormat','table', 'ReadKey', app.ReadAPIKey);
            app.PlayerHand = string2cards(app, string(cardsStringTable.Player2Cards));            
            
            % Initialized discard pile
            cardsStringTable = thingSpeakRead(app.ChannelID,'Fields',[3], 'OutputFormat','table', 'ReadKey', app.ReadAPIKey);
            cardsString = string(cardsStringTable.DiscardPile);
            card = split(cardsString, "_");
            app.DiscardPileCard = struct('color',{card(1)},'number',{str2num(card(2))},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
            
            while(strcmp(app.UIFigure.Visible, 'off'))
                pause(1);
            end
        end

        % Button pushed function: nextButton
        function nextCards(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            
            % Function is similar to the one found in UnoGameMultiplayer.mlapp
            if(app.PlayerHandStatus + 1 <= floor(length(app.PlayerHand)/7))
                if((mod(length(app.PlayerHand), 7) ~= 0) || (app.PlayerHandStatus + 1 < floor(length(app.PlayerHand)/7)))
                    app.PlayerHandStatus = app.PlayerHandStatus + 1;
                end
            end
            displayPlayerCards(app, (1 + app.PlayerHandStatus*7));
        end

        % Button pushed function: prevButton
        function prevCards(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            
            Function is similar to the one found in UnoGameMultiplayer.mlapp
            if(app.PlayerHandStatus - 1 >= 0)
                app.PlayerHandStatus = app.PlayerHandStatus - 1;
            end
            displayPlayerCards(app, (1 + app.PlayerHandStatus*7));
        end

        % Button pushed function: MenuButton
        function MenuButtonPushed(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            
            app.home_screen_app.UIFigure.Visible = 'on'; % Turns the menu visibility on
            app.UIFigure.Visible = 'off'; % Turn Game Visibility off
        end

        % Image clicked function: DrawDeck
        function DrawDeckImageClicked(app, event)
            if(app.Turn == 1)
                [y, Fs] = audioread('drawcard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                
                thingspeakData = thingSpeakRead(app.ChannelID, 'OutputFormat','table', 'ReadKey', app.ReadAPIKey);
                
                playerCards = "";
                for i=1:length(app.PlayerHand)
                    colorChar = char(app.PlayerHand(i).color);
                    playerCards = playerCards + colorChar(1) + app.PlayerHand(i).number + "_";
                end
                
                pause(1);
                thingSpeakWrite(app.ChannelID, {thingspeakData.Player1CardsNum, char(playerCards), char(app.DiscardPileCard.color + "_" + app.DiscardPileCard.number), 1, thingspeakData.ValidUno, app.Turn, 0},'WriteKey',app.WriteAPIKey);
                
                pause(2);
                thingspeakData = thingSpeakRead(app.ChannelID, 'OutputFormat','table', 'ReadKey', app.ReadAPIKey);
                
                app.OpponentHandNum = thingspeakData.Player1CardsNum;
                displayOpponentCards(app);
                
                app.PlayerHand = string2cards(app, string(thingspeakData.Player2Cards));
                displayPlayerCards(app, (1 + app.PlayerHandStatus*7));
                
                cardsString = string(thingspeakData.DiscardPile);
                card = split(cardsString, "_");
                app.DiscardPileCard = struct('color',{card(1)},'number',{str2num(card(2))},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                displayDiscardPile(app);
                
                app.Turn = thingspeakData.Turn;
                
                while(thingspeakData.DrawCard ~= 0)
                    
                    if(strcmp(app.SplashScreen.Visible, 'on'))
                        break;
                    end
                    
                    app.OpponentHandNum = thingspeakData.Player1CardsNum;
                    displayOpponentCards(app);
                    
                    app.PlayerHand = string2cards(app, string(thingspeakData.Player2Cards));
                    displayPlayerCards(app, (1 + app.PlayerHandStatus*7));
                    
                    cardsString = string(thingspeakData.DiscardPile);
                    card = split(cardsString, "_");
                    app.DiscardPileCard = struct('color',{card(1)},'number',{str2num(card(2))},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                    displayDiscardPile(app);
                    
                    app.Turn = thingspeakData.Turn;
                end
                
                displayPlayerCards(app, (1 + app.PlayerHandStatus*7))
            end
        end

        % Image clicked function: UnoButton
        function UnoButtonImageClicked(app, event)
            % Function is similar to the one found in UnoGame.mlapp
            if(length(app.PlayerHand) == 1)
                [y, Fs] = audioread('button.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                app.ValidUno = 2;
                thingspeakData = thingSpeakRead(app.ChannelID, 'OutputFormat','table', 'ReadKey', app.ReadAPIKey);
                pause(3);
                thingSpeakWrite(app.ChannelID, {thingspeakData.Player1CardsNum, char(thingspeakData.Player2Cards), char(app.DiscardPileCard.color + "_" + app.DiscardPileCard.number), 0, app.ValidUno, app.Turn, 0},'WriteKey',app.WriteAPIKey);
            end
        end

        % Image clicked function: PlayerCard1
        function PlayerCard1ImageClicked(app, event)
            % Function is similar to the one found in UnoGame.mlapp
            if(app.Turn == 1)
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (1 + app.PlayerHandStatus*7));
            end
        end

        % Image clicked function: PlayerCard2
        function PlayerCard2ImageClicked(app, event)
            if(app.Turn == 1)
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (2 + app.PlayerHandStatus*7));
            end
        end

        % Image clicked function: PlayerCard3
        function PlayerCard3ImageClicked(app, event)
            if(app.Turn == 1)
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (3 + app.PlayerHandStatus*7));
            end
        end

        % Image clicked function: PlayerCard4
        function PlayerCard4ImageClicked(app, event)
            if(app.Turn == 1)
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (4 + app.PlayerHandStatus*7));
            end
        end

        % Image clicked function: PlayerCard5
        function PlayerCard5ImageClicked(app, event)
            if(app.Turn == 1)
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (5 + app.PlayerHandStatus*7));
            end
        end

        % Image clicked function: PlayerCard6
        function PlayerCard6ImageClicked(app, event)
            if(app.Turn == 1)
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (6 + app.PlayerHandStatus*7));
            end
        end

        % Image clicked function: PlayerCard7
        function PlayerCard7ImageClicked(app, event)
            if(app.Turn == 1)
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (7 + app.PlayerHandStatus*7));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.SplashScreen.ImageSource = 'Blank.png';
            app.SplashScreen.Visible = 'on';
            pause(2);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = 'Table_0.png';
            app.PlayerCard1 = uiimage(app.UIFigure);
            app.PlayerCard1.ImageClickedFcn = createCallbackFcn(app, @PlayerCard1ImageClicked, true);
            app.PlayerCard2 = uiimage(app.UIFigure);
            app.PlayerCard2.ImageClickedFcn = createCallbackFcn(app, @PlayerCard2ImageClicked, true);
            app.PlayerCard3 = uiimage(app.UIFigure);
            app.PlayerCard3.ImageClickedFcn = createCallbackFcn(app, @PlayerCard3ImageClicked, true);
            app.PlayerCard4 = uiimage(app.UIFigure);
            app.PlayerCard4.ImageClickedFcn = createCallbackFcn(app, @PlayerCard4ImageClicked, true);
            app.PlayerCard5 = uiimage(app.UIFigure);
            app.PlayerCard5.ImageClickedFcn = createCallbackFcn(app, @PlayerCard5ImageClicked, true);
            app.PlayerCard6 = uiimage(app.UIFigure);
            app.PlayerCard6.ImageClickedFcn = createCallbackFcn(app, @PlayerCard6ImageClicked, true);
            app.PlayerCard7 = uiimage(app.UIFigure);
            app.PlayerCard7.ImageClickedFcn = createCallbackFcn(app, @PlayerCard7ImageClicked, true);
            app.nextButton = uibutton(app.UIFigure, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextCards, true);
            app.nextButton.Text = '→';
            app.prevButton = uibutton(app.UIFigure, 'push');
            app.prevButton.ButtonPushedFcn = createCallbackFcn(app, @prevCards, true);
            app.prevButton.Text = '←';
            app.OpponentCard7 = uiimage(app.UIFigure);
            app.OpponentCard7.ImageSource = 'Deck_.png';
            app.OpponentCard6 = uiimage(app.UIFigure);
            app.OpponentCard6.ImageSource = 'Deck_.png';
            app.OpponentCard1 = uiimage(app.UIFigure);
            app.OpponentCard1.ImageSource = 'Deck_.png';
            app.OpponentCard5 = uiimage(app.UIFigure);
            app.OpponentCard5.ImageSource = 'Deck_.png';
            app.OpponentCard2 = uiimage(app.UIFigure);
            app.OpponentCard2.ImageSource = 'Deck_.png';
            app.OpponentCard4 = uiimage(app.UIFigure);
            app.OpponentCard4.ImageSource = 'Deck_.png';
            app.OpponentCard3 = uiimage(app.UIFigure);
            app.OpponentCard3.ImageSource = 'Deck_.png';
            app.OpponentCardsLabel = uilabel(app.UIFigure);
            app.OpponentCardsLabel.Text = {'OPPONENT'; 'CARDS'};
            app.OpponentCardsValue = uilabel(app.UIFigure);
            app.OpponentCardsValue.Text = '12';
            app.MenuButton = uibutton(app.UIFigure, 'push');
            app.MenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton.Text = 'MENU';
            app.DiscardPileImage = uiimage(app.UIFigure);
            app.DrawDeck = uiimage(app.UIFigure);
            app.DrawDeck.ImageClickedFcn = createCallbackFcn(app, @DrawDeckImageClicked, true);
            app.DrawDeck.ImageSource = 'Deck_.png';
            app.DrawDeckLabel = uilabel(app.UIFigure);
            app.DrawDeckLabel.Text = {'DRAW'; 'DECK'};
            app.DiscardPileLabel = uilabel(app.UIFigure);
            app.DiscardPileLabel.Text = {'DISCARD'; 'PILE'};
            app.UnoButton = uiimage(app.UIFigure);
            app.UnoButton.ImageClickedFcn = createCallbackFcn(app, @UnoButtonImageClicked, true);
            app.UnoButton.ImageSource = 'UNOLogo.png';
            app.UnoButtonLabel = uilabel(app.UIFigure);
            app.UnoButtonLabel.Text = {'UNO'; 'BUTTON'};
            app.TurnLabel = uilabel(app.UIFigure);
            app.TurnLabel.Text = {'PLEASE WAIT'; 'FOR YOUR'; 'TURN'};
            app.SplashScreen = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UnoGameClient(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end