classdef UnoGameMultiplayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Background          matlab.ui.control.Image
        PlayerCard1         matlab.ui.control.Image
        PlayerCard2         matlab.ui.control.Image
        PlayerCard3         matlab.ui.control.Image
        PlayerCard4         matlab.ui.control.Image
        PlayerCard5         matlab.ui.control.Image
        PlayerCard6         matlab.ui.control.Image
        PlayerCard7         matlab.ui.control.Image
        nextButton          matlab.ui.control.Button
        prevButton          matlab.ui.control.Button
        OpponentCard7       matlab.ui.control.Image
        OpponentCard6       matlab.ui.control.Image
        OpponentCard1       matlab.ui.control.Image
        OpponentCard5       matlab.ui.control.Image
        OpponentCard2       matlab.ui.control.Image
        OpponentCard4       matlab.ui.control.Image
        OpponentCard3       matlab.ui.control.Image
        OpponentCardsLabel  matlab.ui.control.Label
        OpponentCardsValue  matlab.ui.control.Label
        MenuButton          matlab.ui.control.Button
        DiscardPileImage    matlab.ui.control.Image
        DrawDeck            matlab.ui.control.Image
        DrawDeckLabel       matlab.ui.control.Label
        DiscardPileLabel    matlab.ui.control.Label
        UnoButton           matlab.ui.control.Image
        UnoButtonLabel      matlab.ui.control.Label
        TurnLabel           matlab.ui.control.Label
        SplashScreen        matlab.ui.control.Image
    end

    
    properties (Access = public)
        PlayerHandStatus = 0; % To check which set of player cards we want to display
        ValidUno = 0; % Checks if the Uno Button has been pressed correctly
        Turn; % Determines which Player's turn it is.
        OriginalDeck; % 2D Struct array that stores and keeps track of the original deck of cards
        PlayerHand; % Struct array that keep strack of the player hand
        OpponentHand;
        DiscardPileCard; % Struct that stores the top of the Discard Pile
        home_screen_app; % Used to go back to the main menu
        deckChoice = "_";
    end
    
    properties (Access = private)
        ChannelID = 1253923;
        WriteAPIKey = 'AXU8658453CFLG03'; % Description
        ReadAPIKey = 'P8REAMX0A7W127U9';
    end
    
    methods (Access = public)
        function ar = initDeck(app) % Initializes the Original Deck
            ar = [];
            ar1 = [];
            ar2 = [];
            ar3 = [];
            ar4 = [];
            for i=0:14 % Initializing all Red Cards
                s = struct('color',{"Red"},'number',{i},'OriginalDeckNum', {2}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                ar1 = [ar1 s];
            end
            
            for i=0:14 % Initializing all Yellow Cards
                s = struct('color',{"Yellow"},'number',{i},'OriginalDeckNum', {2}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                ar2 = [ar2 s];
            end
            
            for i=0:14 % Initializing all Green Cards
                s = struct('color',{"Green"},'number',{i},'OriginalDeckNum', {2}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                ar3 = [ar3 s];
            end
            
            for i=0:14 % Initializing all Blue Cards
                s = struct('color',{"Blue"},'number',{i},'OriginalDeckNum', {2}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                ar4 = [ar4 s];
            end
            
            ar = [ar1; ar2; ar3; ar4]; % Constructing 2D Array
            
            % We want a single 0-Numbered Card per Color.
            for i=1:4
                ar(i,1).OriginalDeckNum = 1;
            end
            
            % We want 4 Wild Cards and 4 Wild Draw Cards
            ar(1, 13).OriginalDeckNum = 4;
            ar(1, 14).OriginalDeckNum = 4;
            
            % Making the rest of the Cards Blank
            for i=2:4
                ar(i,13) = struct('color',{"Blank"},'number',{0},'OriginalDeckNum', {-1}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                ar(i,14) = struct('color',{"Blank"},'number',{0},'OriginalDeckNum', {-1}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
            end
        end
        
        % Generated a Random Card to be used by inital Hand Generation
        % Functions, Draw Card Button, +2 and Wild +4 Cards, and if Uno
        % Button has not been pressed.
        function cardDrawn = generateRandomCard(app)
            % Creates a Blank Card to ensure that a valid color card has
            % been chosen
            cardDrawn = struct('color',{"Blank"},'number',{0},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
            while(strcmp(cardDrawn.color, "Blank"))
                i = randi([1,4]); % Chooses a random color
                j = randi([1,15]); % Chooses a random number
                if(app.OriginalDeck(i,j).OriginalDeckNum > 0) % Ensures that we don't cross the limit of 108 Cards
                    cardDrawn = app.OriginalDeck(i,j);
                end
            end
        end
        
        
        function results = initPlayerHand(app) % Initializes the Player Hand with 7 Cards
            results = [];
            for i=1:7
                results = [results generateRandomCard(app)];
                updateDeck(app, results(i).color, results(i).number, -1, 1, 0, 0); % Update the Original Deck to keep track of cards
            end
        end
        
        function results = initOpponentHand(app)
            results = [];
            for i=1:7
                results = [results generateRandomCard(app)];
                updateDeck(app, results(i).color, results(i).number, -1, 0, 1, 0); % Update the Original Deck to keep track of cards
            end
        end
        
        function playCard(app, i) % Determines if we can Play a Selected Card and if yes, how to go about it
            % Checks to see if the color/number of the discard pile and
            % card to play are the same, or if the card to be played is a
            % Wild / Wild + 4 Card
            if(strcmp(app.PlayerHand(i).color, app.DiscardPileCard.color) || (app.PlayerHand(i).number == app.DiscardPileCard.number) || (app.PlayerHand(i).number > 12))
                % The WildChoice App changes the color of the Wild/Wild +
                % 4 Cards for display purposes, but we want to revert it
                % back to the default "Red" to ensure that we don't exceed
                % the deck size.
                
                thingspeakData = readData(app);
                
                app.OpponentHand = string2cards(app, string(thingspeakData.Player2Cards));
                
                if(app.DiscardPileCard.number >= 13)
                    app.DiscardPileCard.color = "Red";
                end
                
                updateDeck(app, app.DiscardPileCard.color, app.DiscardPileCard.number, 1, 0, 0, -1); % Update the deck
                
                app.DiscardPileCard = app.PlayerHand(i); % Change the discardPile to the card being played
                
                
                if((length(app.PlayerHand) == 1) && (app.ValidUno == 1)) % If player is down to one card, and has pressed the uno button, they can win
                    app.SplashScreen.Visible = 'on';
                    app.SplashScreen.ImageSource = 'WinnerScreen.gif';
                    [y, Fs] = audioread('victory.wav');   % reads the audio
                    sound(y,Fs);    % plays the audio
                elseif((length(app.PlayerHand) == 1) && (app.ValidUno ~= 1)) % The player forgot to press the uno button, so they will have to draw 4 cards
                    for j=1:4
                        cardGenerated = generateRandomCard(app);
                        app.PlayerHand = [app.PlayerHand cardGenerated];
                        updateDeck(app, cardGenerated.color, cardGenerated.number, -1, 1, 0, 0);
                    end
                end
                
                if(app.ValidUno ~= 2) % We don't want to modify the 2nd Player's Uno Status
                    app.ValidUno = 0;
                end
                
                app.PlayerHand(i) = []; % Delete the card played from the player's hand
                
                updateDeck(app, app.DiscardPileCard.color, app.DiscardPileCard.number, 0, -1, 0, 1); % Update the deck
                
                if(app.DiscardPileCard.number == 13) % If the card played is a Wild Card, prompt user for color choice
                    WildChoice(app);
                elseif(app.DiscardPileCard.number == 14) % If the card played is a Wild +4 Card, prompt user for color choice and add 4 cards to Player 2's hand
                    WildChoice(app);
                    for j=1:4
                        cardGenerated = generateRandomCard(app);
                        app.OpponentHand = [app.OpponentHand cardGenerated];
                        updateDeck(app, cardGenerated.color, cardGenerated.number, -1, 0, 1, 0);
                    end
                else
                    displayDiscardPile(app); % Updates the Discard Pile Display
                end
                
                if(app.DiscardPileCard.number == 12) % Draw 2
                    for j=1:2
                        cardGenerated = generateRandomCard(app);
                        app.OpponentHand = [app.OpponentHand cardGenerated];
                        updateDeck(app, cardGenerated.color, cardGenerated.number, -1, 0, 1, 0);
                    end
                    app.Turn = 0; % Since it's a 2-Player Game, the +2 Card keeps control with Player 1
                elseif(app.DiscardPileCard.number == 11) % Since it's a 2-Player Game, the reverse card keeps control with Player 1
                    app.Turn = 0;
                elseif(app.DiscardPileCard.number == 10) % Since it's a 2-Player Game, the skip card keeps control with Player 1
                    app.Turn = 0;
                else
                    app.Turn = 1; % It's Player 2's Turn
                end
                
                displayOpponentCards(app) % Refreshes Card GUI
                displayPlayerCards(app, (1 + app.PlayerHandStatus*7));
                
                opponentCards = cards2string(app);
                
                pause(1);
                thingSpeakWrite(app.ChannelID, {length(app.PlayerHand), char(opponentCards), char(app.DiscardPileCard.color + "_" + app.DiscardPileCard.number), 0, app.ValidUno, app.Turn, 0},'WriteKey',app.WriteAPIKey);
                
                if(app.Turn == 1)
                    thingspeakData = readData(app);
                    tempTurn = thingspeakData.Turn;
                    
                    while(tempTurn ~= 0)
                        
                        if(strcmp(app.SplashScreen.Visible, 'on'))
                            break;
                        end
                        
                        pause(1);
                        thingspeakData = readData(app);
                        if(thingspeakData.PlayCard == 1)
                            cardsString = string(thingspeakData.DiscardPile);
                            card = split(cardsString, "_");
                            newDiscardPileCard = struct('color',{card(1)},'number',{str2num(card(2))},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                            playOpponentCard(app);
                            app.DiscardPileCard = newDiscardPileCard;
                            displayDiscardPile(app);
                        end
                        
                        if(thingspeakData.DrawCard == 1)
                            newCard = generateRandomCard(app);
                            updateDeck(app, newCard.color, newCard.number, -1, 0, 1, 0);
                            
                            app.OpponentHand = [app.OpponentHand newCard];
                            
                            opponentCards = cards2string(app);
                            
                            pause(1);
                            thingSpeakWrite(app.ChannelID, {length(app.PlayerHand), char(opponentCards), char(app.DiscardPileCard.color + "_" + app.DiscardPileCard.number), 0, app.ValidUno, app.Turn, 0},'WriteKey',app.WriteAPIKey);
                            displayOpponentCards(app);
                        end
                        tempTurn = thingspeakData.Turn;
                    end
                end
            end
            
            thingspeakData = readData(app);
            
            app.OpponentHand = string2cards(app, string(thingspeakData.Player2Cards));
            
            opponentCards = cards2string(app);
            
            pause(2);
            thingSpeakWrite(app.ChannelID, {length(app.PlayerHand), char(opponentCards), char(app.DiscardPileCard.color + "_" + app.DiscardPileCard.number), 0, app.ValidUno, app.Turn, 0},'WriteKey',app.WriteAPIKey);
        end
        
        function playOpponentCard(app)
            cardsStringTable = thingSpeakRead(app.ChannelID,'Fields',[3], 'OutputFormat','table', 'ReadKey', app.ReadAPIKey);
            cardsString = string(cardsStringTable.DiscardPile);
            card = split(cardsString, "_");
            card = struct('color',{card(1)},'number',{str2num(card(2))},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
            updateDeck(app, card.color, card.number, 0, 0, -1, 1);
            
            if(app.DiscardPileCard.number >= 13)
                app.DiscardPileCard.color = "Red";
            end
            
            pause(1);
            thingspeakData = readData(app);
            
            app.OpponentHand = string2cards(app, string(thingspeakData.Player2Cards));
            app.ValidUno = thingspeakData.ValidUno;
            updateDeck(app, app.DiscardPileCard.color, app.DiscardPileCard.number, 1, 0, 0, -1); % Update the deck
            
            app.DiscardPileCard = card; % Change the discardPile to the card being played
            
            displayDiscardPile(app);
            
            if((length(app.OpponentHand) <= 1) && (app.ValidUno == 2)) % If player is down to one card, and has pressed the uno button, they can win
                app.SplashScreen.Visible = 'on'; % Turns the splash screen images to visible
                app.SplashScreen.ImageSource = 'Loser.png'; % Player 1 is the Winner
                [y, Fs] = audioread('loser.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
            elseif((length(app.OpponentHand) == 1) && (app.ValidUno ~= 2)) % The player forgot to press the uno button, so they will have to draw 4 cards
                pause(4);
                thingspeakData = readData(app);
                if(thingspeakData.ValidUno ~= 2)
                    for j=1:4
                        cardGenerated = generateRandomCard(app);
                        app.OpponentHand = [app.OpponentHand cardGenerated];
                        updateDeck(app, cardGenerated.color, cardGenerated.number, -1, 0, 1, 0);
                    end
                end
            end
            
            if(app.ValidUno ~= 1) % We don't want to modify the 1st Player's Uno Status
                app.ValidUno = 0;
            end
            
            if(app.DiscardPileCard.number == 13) % If the card played is a Wild Card, prompt user for color choice
                pause(4);
                
                cardsStringTable = thingSpeakRead(app.ChannelID,'Fields',[3], 'OutputFormat','table', 'ReadKey', app.ReadAPIKey);
                cardsString = string(cardsStringTable.DiscardPile);
                card = split(cardsString, "_");
                app.DiscardPileCard = struct('color',{card(1)},'number',{str2num(card(2))},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                displayDiscardPile(app);
            elseif(app.DiscardPileCard.number == 14) % If the card played is a Wild +4 Card, prompt user for color choice and add 4 cards to Player 2's hand
                pause(4);
                
                cardsStringTable = thingSpeakRead(app.ChannelID,'Fields',[3], 'OutputFormat','table', 'ReadKey', app.ReadAPIKey);
                cardsString = string(cardsStringTable.DiscardPile);
                card = split(cardsString, "_");
                app.DiscardPileCard = struct('color',{card(1)},'number',{str2num(card(2))},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                displayDiscardPile(app);
                
                for j=1:4
                    cardGenerated = generateRandomCard(app);
                    app.PlayerHand = [app.PlayerHand cardGenerated];
                    updateDeck(app, cardGenerated.color, cardGenerated.number, -1, 1, 0, 0);
                end
            else
                displayDiscardPile(app); % Updates the Discard Pile Display
            end
            
            if(app.DiscardPileCard.number == 12) % Draw 2
                for j=1:2
                    cardGenerated = generateRandomCard(app);
                    app.PlayerHand = [app.PlayerHand cardGenerated];
                    updateDeck(app, cardGenerated.color, cardGenerated.number, -1, 1, 0, 0);
                end
                app.Turn = 1; % Since it's a 2-Player Game, the +2 Card keeps control with Player 1
            elseif(app.DiscardPileCard.number == 11) % Since it's a 2-Player Game, the reverse card keeps control with Player 1
                app.Turn = 1;
            elseif(app.DiscardPileCard.number == 10) % Since it's a 2-Player Game, the skip card keeps control with Player 1
                app.Turn = 1;
            else
                app.Turn = 0; % It's Player 1's Turn
            end
            
            displayOpponentCards(app) % Refreshes Card GUI
            displayPlayerCards(app, (1 + app.PlayerHandStatus*7));
            displayDiscardPile(app);
            
            thingspeakData = readData(app);
            
            app.OpponentHand = string2cards(app, string(thingspeakData.Player2Cards));
            
            opponentCards = cards2string(app);
            
            pause(2);
            thingSpeakWrite(app.ChannelID, {length(app.PlayerHand), char(opponentCards), char(app.DiscardPileCard.color + "_" + app.DiscardPileCard.number), 0, app.ValidUno, app.Turn, 0},'WriteKey',app.WriteAPIKey);
            
            thingspeakData = readData(app);
            
            if(app.Turn == 0)
                thingspeakData = readData(app);
                tempTurn = thingspeakData.Turn;
                
                while(tempTurn ~= 1)
                    
                    if(strcmp(app.SplashScreen.Visible, 'on'))
                        break;
                    end
                    
                    pause(1);
                    thingspeakData = readData(app);
                    if(thingspeakData.PlayCard == 1)
                        cardsString = string(thingspeakData.DiscardPile);
                        card = split(cardsString, "_");
                        newDiscardPileCard = struct('color',{card(1)},'number',{str2num(card(2))},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                        playOpponentCard(app);
                        app.DiscardPileCard = newDiscardPileCard;
                        displayDiscardPile(app);
                    end
                    tempTurn = thingspeakData.Turn;
                end
            end
            
            app.OpponentHand = string2cards(app, string(thingspeakData.Player2Cards));
            
            opponentCards = cards2string(app);
            
            pause(1);
            thingSpeakWrite(app.ChannelID, {length(app.PlayerHand), char(opponentCards), char(app.DiscardPileCard.color + "_" + app.DiscardPileCard.number), 0, app.ValidUno, app.Turn, 0},'WriteKey',app.WriteAPIKey);
            
        end
        
        % Updates the original deck so we can keep track of cards
        function updateDeck(app, color, number, OriginalDeckUpdate, PlayerHandUpdate, OpponentHandUpdate, DiscardPileUpdate)
            % Searches for the card in the original deck
            for i=1:4
                for j=1:15
                    if(strcmp(app.OriginalDeck(i,j).color, color) && (app.OriginalDeck(i,j).number == number))
                        app.OriginalDeck(i,j).OriginalDeckNum = app.OriginalDeck(i,j).OriginalDeckNum + OriginalDeckUpdate; % Updates values
                        app.OriginalDeck(i,j).PlayerHandNum = app.OriginalDeck(i,j).PlayerHandNum + PlayerHandUpdate;
                        app.OriginalDeck(i,j).OpponentHandNum = app.OriginalDeck(i,j).OpponentHandNum + OpponentHandUpdate;
                        app.OriginalDeck(i,j).DiscardPileNum = app.OriginalDeck(i,j).DiscardPileNum + DiscardPileUpdate;
                    end
                end
            end
        end
        
        function displayPlayerCards(app, i) % Displays Player Cards at the bottom
            if(app.Turn == 0)
                app.TurnLabel.Text = {'YOUR TURN'; 'PLAY'; 'NOW'};
            else
                app.TurnLabel.Text = {'PLEASE WAIT'; 'FOR YOUR'; 'TURN'};
            end
            
            if(i <= length(app.PlayerHand)) % Display the 'i'-th Card only if there are actually a minimum of i Cards in the Player Hand
                app.PlayerCard1.Visible = 'on';
                if(app.PlayerHand(i).number == 13) % Display Generic Wild Card
                    app.PlayerCard1.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i).number == 14) % Display Generic Wild +4 Card
                    app.PlayerCard1.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else % Displays Color Cards (Number + Action Cards)
                    app.PlayerCard1.ImageSource = char(app.PlayerHand(i).color + app.deckChoice + app.PlayerHand(i).number + ".png");
                end
            else
                app.PlayerCard1.Visible = 'off'; % The 'i'-th Card doesn't exist in the deck, so don't display it
            end
            
            if(i+1 <=length(app.PlayerHand))
                app.PlayerCard2.Visible = 'on';
                if(app.PlayerHand(i+1).number == 13)
                    app.PlayerCard2.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i+1).number == 14)
                    app.PlayerCard2.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else
                    app.PlayerCard2.ImageSource = char(app.PlayerHand(i+1).color + app.deckChoice + app.PlayerHand(i+1).number + ".png");
                end
            else
                app.PlayerCard2.Visible = 'off';
            end
            
            if(i+2 <= length(app.PlayerHand))
                app.PlayerCard3.Visible = 'on';
                if(app.PlayerHand(i+2).number == 13)
                    app.PlayerCard3.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i+2).number == 14)
                    app.PlayerCard3.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else
                    app.PlayerCard3.ImageSource = char(app.PlayerHand(i+2).color + app.deckChoice + app.PlayerHand(i+2).number + ".png");
                end
            else
                app.PlayerCard3.Visible = 'off';
            end
            
            if(i+3 <= length(app.PlayerHand))
                app.PlayerCard4.Visible = 'on';
                if(app.PlayerHand(i+3).number == 13)
                    app.PlayerCard4.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i+3).number == 14)
                    app.PlayerCard4.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else
                    app.PlayerCard4.ImageSource = char(app.PlayerHand(i+3).color + app.deckChoice + app.PlayerHand(i+3).number + ".png");
                end
            else
                app.PlayerCard4.Visible = 'off';
            end
            
            if(i+4 <= length(app.PlayerHand))
                app.PlayerCard5.Visible = 'on';
                if(app.PlayerHand(i+4).number == 13)
                    app.PlayerCard5.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i+4).number == 14)
                    app.PlayerCard5.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else
                    app.PlayerCard5.ImageSource = char(app.PlayerHand(i+4).color + app.deckChoice + app.PlayerHand(i+4).number + ".png");
                end
            else
                app.PlayerCard5.Visible = 'off';
            end
            
            if(i+5 <= length(app.PlayerHand))
                app.PlayerCard6.Visible = 'on';
                if(app.PlayerHand(i+5).number == 13)
                    app.PlayerCard6.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i+5).number == 14)
                    app.PlayerCard6.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else
                    app.PlayerCard6.ImageSource = char(app.PlayerHand(i+5).color + app.deckChoice + app.PlayerHand(i+5).number + ".png");
                end
            else
                app.PlayerCard6.Visible = 'off';
            end
            
            if(i+6 <= length(app.PlayerHand))
                app.PlayerCard7.Visible = 'on';
                if(app.PlayerHand(i+6).number == 13)
                    app.PlayerCard7.ImageSource = char("Wild" + app.deckChoice + ".png");
                elseif(app.PlayerHand(i+6).number == 14)
                    app.PlayerCard7.ImageSource = char("WildDraw" + app.deckChoice + ".png");
                else
                    app.PlayerCard7.ImageSource = char(app.PlayerHand(i+6).color + app.deckChoice + app.PlayerHand(i+6).number + ".png");
                end
            else
                app.PlayerCard7.Visible = 'off';
            end
        end
        
        function displayOpponentCards(app) % Displays the Opponent's Hands
            app.OpponentCardsValue.Text = num2str(length(app.OpponentHand)); % Display for the Number of Cards the Opponent has
            
            if(isempty(app.OpponentHand))
                app.SplashScreen.Visible = 'on'; % Turns the splash screen images to visible
                app.SplashScreen.ImageSource = 'Loser.png'; % Player 1 is the Winner
                [y, Fs] = audioread('loser.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
            end
            
            app.OpponentCard1.ImageSource = char('Deck' + app.deckChoice + ".png");
            app.OpponentCard2.ImageSource = char('Deck' + app.deckChoice + ".png");
            app.OpponentCard3.ImageSource = char('Deck' + app.deckChoice + ".png");
            app.OpponentCard4.ImageSource = char('Deck' + app.deckChoice + ".png");
            app.OpponentCard5.ImageSource = char('Deck' + app.deckChoice + ".png");
            app.OpponentCard6.ImageSource = char('Deck' + app.deckChoice + ".png");
            app.OpponentCard7.ImageSource = char('Deck' + app.deckChoice + ".png");
            
            switch(length(app.OpponentHand))
                case 1 % If the opponent only has one card, display only the first card
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'off';
                    app.OpponentCard3.Visible = 'off';
                    app.OpponentCard4.Visible = 'off';
                    app.OpponentCard5.Visible = 'off';
                    app.OpponentCard6.Visible = 'off';
                    app.OpponentCard7.Visible = 'off';
                case 2 % If the opponent has 2 cards, display 2 opponent cards
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'on';
                    app.OpponentCard3.Visible = 'off';
                    app.OpponentCard4.Visible = 'off';
                    app.OpponentCard5.Visible = 'off';
                    app.OpponentCard6.Visible = 'off';
                    app.OpponentCard7.Visible = 'off';
                case 3
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'on';
                    app.OpponentCard3.Visible = 'on';
                    app.OpponentCard4.Visible = 'off';
                    app.OpponentCard5.Visible = 'off';
                    app.OpponentCard6.Visible = 'off';
                    app.OpponentCard7.Visible = 'off';
                case 4
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'on';
                    app.OpponentCard3.Visible = 'on';
                    app.OpponentCard4.Visible = 'on';
                    app.OpponentCard5.Visible = 'off';
                    app.OpponentCard6.Visible = 'off';
                    app.OpponentCard7.Visible = 'off';
                case 5
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'on';
                    app.OpponentCard3.Visible = 'on';
                    app.OpponentCard4.Visible = 'on';
                    app.OpponentCard5.Visible = 'on';
                    app.OpponentCard6.Visible = 'off';
                    app.OpponentCard7.Visible = 'off';
                case 6
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'on';
                    app.OpponentCard3.Visible = 'on';
                    app.OpponentCard4.Visible = 'on';
                    app.OpponentCard5.Visible = 'on';
                    app.OpponentCard6.Visible = 'on';
                    app.OpponentCard7.Visible = 'off';
                otherwise % Opponent either has >= 7 Cards, or has no cards at all (game win)
                    app.OpponentCard1.Visible = 'on';
                    app.OpponentCard2.Visible = 'on';
                    app.OpponentCard3.Visible = 'on';
                    app.OpponentCard4.Visible = 'on';
                    app.OpponentCard5.Visible = 'on';
                    app.OpponentCard6.Visible = 'on';
                    app.OpponentCard7.Visible = 'on';
            end
        end
        
        % Displays DiscardPile Image
        function displayDiscardPile(app)
            app.DiscardPileImage.ImageSource = char(app.DiscardPileCard.color + app.deckChoice + app.DiscardPileCard.number + ".png");
        end
        
        % Initializes all Card GUIs for the Player
        function initDisplay(app)
            displayDiscardPile(app);
            displayPlayerCards(app, 1);
            displayOpponentCards(app);
        end
        
        function results = string2cards(app, cardsString)
            
            results = [];
            card = split(cardsString, "_");
            card(end) = [];
            for i=1:length(card)
                if(startsWith(card(i), "B"))
                    s = sscanf(card(i), 'B%d');
                    s = struct('color',{"Blue"},'number',{s},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                    results = [results s];
                elseif(startsWith(card(i),"R"))
                    s = sscanf(card(i), 'R%d');
                    s = struct('color',{"Red"},'number',{s},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                    results = [results s];
                elseif(startsWith(card(i),"Y"))
                    s = sscanf(card(i), 'Y%d');
                    s = struct('color',{"Yellow"},'number',{s},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                    results = [results s];
                elseif(startsWith(card(i),"G"))
                    s = sscanf(card(i), 'G%d');
                    s = struct('color',{"Green"},'number',{s},'OriginalDeckNum', {0}, 'PlayerHandNum',{0}, 'OpponentHandNum', {0}, 'DiscardPileNum', {0});
                    results = [results s];
                end
            end
        end
        
        function opponentCards = cards2string(app)
            opponentCards = "";
            for i=1:length(app.OpponentHand)
                colorChar = char(app.OpponentHand(i).color);
                opponentCards = opponentCards + colorChar(1) + app.OpponentHand(i).number + "_";
            end
        end
    end
    
    methods (Access = private)
        
        % Syncs ThingSpeakData between
        function thingspeakData = readData(app)
            thingspeakData = thingSpeakRead(app.ChannelID, 'OutputFormat','table', 'ReadKey', app.ReadAPIKey);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, home_screen)
            app.home_screen_app = home_screen; % Import the home screen app
            app.SplashScreen.Visible = 'off'; % Turn Splash Screen off
            app.OriginalDeck = initDeck(app); % Initialize the original deck of 108 cards
            app.PlayerHand = initPlayerHand(app); % Initializes the player hand of 7 cards
            app.DiscardPileCard = generateRandomCard(app); % Initializes discard pile
            while(app.DiscardPileCard.number > 9) % Keeps selecting a new card for the discard pile if the discard pile is an action card
                app.DiscardPileCard = generateRandomCard(app);
            end
            updateDeck(app, app.DiscardPileCard.color, app.DiscardPileCard.number, -1, 0, 0, 1); % Update original deck
            
            app.Turn = 0; % Start off with Player 1 going first
            
            app.OpponentHand = initOpponentHand(app); % Initializes Player 2's Hand
            
            opponentCards = cards2string(app);
            
            pause(1);
            thingSpeakWrite(app.ChannelID, {length(app.PlayerHand), char(opponentCards), char(app.DiscardPileCard.color + "_" + app.DiscardPileCard.number), 0, 0, 0, 0},'WriteKey',app.WriteAPIKey);
            
            initDisplay(app); % Display the GUIs
        end

        % Button pushed function: nextButton
        function nextCards(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            
            if(app.PlayerHandStatus + 1 <= floor(length(app.PlayerHand)/7)) % Ensures we don't go past the current selection of 7 cards into "blank territory"
                % Fix for a bug that allowed the player to go past their
                % hand if they had a a multiple of 7 as their no. of cards
                if((mod(length(app.PlayerHand), 7) ~= 0) || (app.PlayerHandStatus + 1 < floor(length(app.PlayerHand)/7)))
                    app.PlayerHandStatus = app.PlayerHandStatus + 1;
                end
            end
            displayPlayerCards(app, (1 + app.PlayerHandStatus*7)); % Refresh Display
        end

        % Button pushed function: prevButton
        function prevCards(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            
            if(app.PlayerHandStatus - 1 >= 0) % Ensures that we gon't go into "blank territory"
                app.PlayerHandStatus = app.PlayerHandStatus - 1;
            end
            displayPlayerCards(app, (1 + app.PlayerHandStatus*7)); % Refresh Display
        end

        % Button pushed function: MenuButton
        function MenuButtonPushed(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            
            app.home_screen_app.UIFigure.Visible = 'on'; % Turns the menu visibility on
            app.UIFigure.Visible = 'off'; % Turn Game Visibility off
        end

        % Image clicked function: DrawDeck
        function DrawDeckImageClicked(app, event)
            if(app.Turn == 0) % Check that it's the player's turn.
                
                [y, Fs] = audioread('drawcard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                
                if((length(app.PlayerHand) + length(app.OpponentHand) + 1) < 108) % Double checking that we don't exceed 108 cards
                    cardGenerated = generateRandomCard(app); % Generate a random card and update the original deck
                    updateDeck(app, cardGenerated.color, cardGenerated.number, -1, 1, 0, 0);
                    app.PlayerHand = [app.PlayerHand cardGenerated]; % Add the card to the player's hand
                    
                    displayOpponentCards(app); % Refresh Card GUIs
                    displayPlayerCards(app, (1 + app.PlayerHandStatus*7));
                    
                    %displayOpponentCards(app.UnoGameClient);
                    %displayPlayerCards(app.UnoGameClient, (1 + app.UnoGameClient.PlayerHandStatus*7));
                else
                    msgbox('No Cards left in Deck', 'Error','error'); % Display error message.
                end
            end
        end

        % Image clicked function: UnoButton
        function UnoButtonImageClicked(app, event)
            % If the player only has 1 card in their hand, they can press
            % the uno button and it is valid
            if(length(app.PlayerHand) == 1)
                [y, Fs] = audioread('button.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                app.ValidUno = 1;
            end
        end

        % Image clicked function: PlayerCard1
        function PlayerCard1ImageClicked(app, event)
            if(app.Turn == 0) % Checks if it is the player's turn
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (1 + app.PlayerHandStatus*7)); % Plays the card
            end
        end

        % Image clicked function: PlayerCard2
        function PlayerCard2ImageClicked(app, event)
            if(app.Turn == 0)
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (2 + app.PlayerHandStatus*7));
            end
        end

        % Image clicked function: PlayerCard3
        function PlayerCard3ImageClicked(app, event)
            if(app.Turn == 0)
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (3 + app.PlayerHandStatus*7));
            end
        end

        % Image clicked function: PlayerCard4
        function PlayerCard4ImageClicked(app, event)
            if(app.Turn == 0)
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (4 + app.PlayerHandStatus*7));
            end
        end

        % Image clicked function: PlayerCard5
        function PlayerCard5ImageClicked(app, event)
            if(app.Turn == 0)
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (5 + app.PlayerHandStatus*7));
            end
        end

        % Image clicked function: PlayerCard6
        function PlayerCard6ImageClicked(app, event)
            if(app.Turn == 0)
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (6 + app.PlayerHandStatus*7));
            end
        end

        % Image clicked function: PlayerCard7
        function PlayerCard7ImageClicked(app, event)
            if(app.Turn == 0)
                [y, Fs] = audioread('placecard.wav');   % reads the audio
                sound(y,Fs);    % plays the audio
                playCard(app, (7 + app.PlayerHandStatus*7));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.SplashScreen.ImageSource = 'Blank.png';
            app.SplashScreen.Visible = 'on';
            pause(2);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = 'Table_0.png';
            app.PlayerCard1 = uiimage(app.UIFigure);
            app.PlayerCard1.ImageClickedFcn = createCallbackFcn(app, @PlayerCard1ImageClicked, true);
            app.PlayerCard2 = uiimage(app.UIFigure);
            app.PlayerCard2.ImageClickedFcn = createCallbackFcn(app, @PlayerCard2ImageClicked, true);
            app.PlayerCard3 = uiimage(app.UIFigure);
            app.PlayerCard3.ImageClickedFcn = createCallbackFcn(app, @PlayerCard3ImageClicked, true);
            app.PlayerCard4 = uiimage(app.UIFigure);
            app.PlayerCard4.ImageClickedFcn = createCallbackFcn(app, @PlayerCard4ImageClicked, true);
            app.PlayerCard5 = uiimage(app.UIFigure);
            app.PlayerCard5.ImageClickedFcn = createCallbackFcn(app, @PlayerCard5ImageClicked, true);
            app.PlayerCard6 = uiimage(app.UIFigure);
            app.PlayerCard6.ImageClickedFcn = createCallbackFcn(app, @PlayerCard6ImageClicked, true);
            app.PlayerCard7 = uiimage(app.UIFigure);
            app.PlayerCard7.ImageClickedFcn = createCallbackFcn(app, @PlayerCard7ImageClicked, true);
            app.nextButton = uibutton(app.UIFigure, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextCards, true);
            app.nextButton.Text = '→';
            app.prevButton = uibutton(app.UIFigure, 'push');
            app.prevButton.ButtonPushedFcn = createCallbackFcn(app, @prevCards, true);
            app.prevButton.Text = '←';
            app.OpponentCard7 = uiimage(app.UIFigure);
            app.OpponentCard7.ImageSource = 'Deck_.png';
            app.OpponentCard6 = uiimage(app.UIFigure);
            app.OpponentCard6.ImageSource = 'Deck_.png';
            app.OpponentCard1 = uiimage(app.UIFigure);
            app.OpponentCard1.ImageSource = 'Deck_.png';
            app.OpponentCard5 = uiimage(app.UIFigure);
            app.OpponentCard5.ImageSource = 'Deck_.png';
            app.OpponentCard2 = uiimage(app.UIFigure);
            app.OpponentCard2.ImageSource = 'Deck_.png';
            app.OpponentCard4 = uiimage(app.UIFigure);
            app.OpponentCard4.ImageSource = 'Deck_.png';
            app.OpponentCard3 = uiimage(app.UIFigure);
            app.OpponentCard3.ImageSource = 'Deck_.png';
            app.OpponentCardsLabel = uilabel(app.UIFigure);
            app.OpponentCardsLabel.Text = {'OPPONENT'; 'CARDS'};
            app.OpponentCardsValue = uilabel(app.UIFigure);
            app.OpponentCardsValue.Text = '12';
            app.MenuButton = uibutton(app.UIFigure, 'push');
            app.MenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton.Text = 'MENU';
            app.DiscardPileImage = uiimage(app.UIFigure);
            app.DrawDeck = uiimage(app.UIFigure);
            app.DrawDeck.ImageClickedFcn = createCallbackFcn(app, @DrawDeckImageClicked, true);
            app.DrawDeck.ImageSource = 'Deck_.png';
            app.DrawDeckLabel = uilabel(app.UIFigure);
            app.DrawDeckLabel.Text = {'DRAW'; 'DECK'};
            app.DiscardPileLabel = uilabel(app.UIFigure);
            app.DiscardPileLabel.Text = {'DISCARD'; 'PILE'};
            app.UnoButton = uiimage(app.UIFigure);
            app.UnoButton.ImageClickedFcn = createCallbackFcn(app, @UnoButtonImageClicked, true);
            app.UnoButton.ImageSource = 'UNOLogo.png';
            app.UnoButtonLabel = uilabel(app.UIFigure);
            app.UnoButtonLabel.Text = {'UNO'; 'BUTTON'};
            app.TurnLabel = uilabel(app.UIFigure);
            app.TurnLabel.Text = {'PLEASE WAIT'; 'FOR YOUR'; 'TURN'};
            app.SplashScreen = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UnoGameMultiplayer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end