classdef WildChoice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SELECTACOLORLabel  matlab.ui.control.Label
        REDButton          matlab.ui.control.Button
        YELLOWButton       matlab.ui.control.Button
        GREENButton        matlab.ui.control.Button
        BLUEButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        UnoGame;
        
        ChannelID = 1253923;
        WriteAPIKey = 'AXU8658453CFLG03'; % Description
        ReadAPIKey = 'P8REAMX0A7W127U9';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, UnoGame)
            app.UnoGame = UnoGame;
        end

        % Button pushed function: BLUEButton
        function BLUEButtonPushed(app, event)
            app.UnoGame.DiscardPileCard.color = 'Blue'; % Change discard pile cardcolor to blue
            
            appDataTable = thingSpeakRead(app.ChannelID, "OutputFormat", "table", "ReadKey", app.ReadAPIKey);
            
            pause(1.5);
            thingSpeakWrite(app.ChannelID, {appDataTable.Player1CardsNum, char(appDataTable.Player2Cards), char("Blue" + "_" + app.UnoGame.DiscardPileCard.number), appDataTable.DrawCard, appDataTable.ValidUno, appDataTable.Turn, 0},'WriteKey',app.WriteAPIKey);
            
            displayDiscardPile(app.UnoGame); % Update the discard pile GUI
            delete(app); % Close the app
        end

        % Button pushed function: GREENButton
        function GREENButtonPushed(app, event)
            app.UnoGame.DiscardPileCard.color = 'Green';
            
            appDataTable = thingSpeakRead(app.ChannelID, "OutputFormat", "table", "ReadKey", app.ReadAPIKey);

            pause(1.5);
            thingSpeakWrite(app.ChannelID, {appDataTable.Player1CardsNum, char(appDataTable.Player2Cards), char("Green" + "_" + app.UnoGame.DiscardPileCard.number), appDataTable.DrawCard, appDataTable.ValidUno, appDataTable.Turn, 0},'WriteKey',app.WriteAPIKey);
            
            displayDiscardPile(app.UnoGame); % Update the discard pile GUI
            delete(app); % Close the app
        end

        % Button pushed function: YELLOWButton
        function YELLOWButtonPushed(app, event)
            app.UnoGame.DiscardPileCard.color = 'Yellow';
            
            appDataTable = thingSpeakRead(app.ChannelID, "OutputFormat", "table", "ReadKey", app.ReadAPIKey);
            
            pause(1.5);
            thingSpeakWrite(app.ChannelID, {appDataTable.Player1CardsNum, char(appDataTable.Player2Cards), char("Yellow" + "_" + app.UnoGame.DiscardPileCard.number), appDataTable.DrawCard, appDataTable.ValidUno, appDataTable.Turn, 0},'WriteKey',app.WriteAPIKey);
            
            displayDiscardPile(app.UnoGame); % Update the discard pile GUI
            delete(app); % Close the app
        end

        % Button pushed function: REDButton
        function REDButtonPushed(app, event)
            app.UnoGame.DiscardPileCard.color = 'Red';
            
            appDataTable = thingSpeakRead(app.ChannelID, "OutputFormat", "table", "ReadKey", app.ReadAPIKey);
            
            pause(1.5);
            thingSpeakWrite(app.ChannelID, {appDataTable.Player1CardsNum, char(appDataTable.Player2Cards), char("Red" + "_" + app.UnoGame.DiscardPileCard.number), appDataTable.DrawCard, appDataTable.ValidUno, appDataTable.Turn, 0},'WriteKey',app.WriteAPIKey);
            
            displayDiscardPile(app.UnoGame); % Update the discard pile GUI
            delete(app); % Close the app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.SELECTACOLORLabel = uilabel(app.UIFigure);
            app.SELECTACOLORLabel.Text = {'SELECT A COLOR'; ''};
            app.REDButton = uibutton(app.UIFigure, 'push');
            app.REDButton.ButtonPushedFcn = createCallbackFcn(app, @REDButtonPushed, true);
            app.REDButton.Text = 'RED';
            app.YELLOWButton = uibutton(app.UIFigure, 'push');
            app.YELLOWButton.ButtonPushedFcn = createCallbackFcn(app, @YELLOWButtonPushed, true);
            app.YELLOWButton.Text = 'YELLOW';
            app.GREENButton = uibutton(app.UIFigure, 'push');
            app.GREENButton.ButtonPushedFcn = createCallbackFcn(app, @GREENButtonPushed, true);
            app.GREENButton.Text = 'GREEN';
            app.BLUEButton = uibutton(app.UIFigure, 'push');
            app.BLUEButton.ButtonPushedFcn = createCallbackFcn(app, @BLUEButtonPushed, true);
            app.BLUEButton.Text = 'BLUE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WildChoice(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end