classdef home_screen_client < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Background          matlab.ui.control.Image
        PLAYButton          matlab.ui.control.Button
        UNOlogo             matlab.ui.control.Image
        RULESButton         matlab.ui.control.Button
        SETTINGSButton      matlab.ui.control.Button
        tutorialBox         matlab.ui.control.Image
        settingsBox         matlab.ui.control.Image
        RETURNTOMENUButton  matlab.ui.control.Button
        STANDARDButton      matlab.ui.control.Button
        MINIMALISTButton    matlab.ui.control.Button
        REDButton           matlab.ui.control.Button
        TEALButton          matlab.ui.control.Button
        PURPLEButton        matlab.ui.control.Button
        GREENButton         matlab.ui.control.Button
    end

    properties (Access = private)
        UnoGameClient; % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PLAYButton.Visible = 'on'; % Menu begins with 3 buttons, background, and an image logo of UNO
            app.RULESButton.Visible = 'on';
            app.SETTINGSButton.Visible = 'on';
            app.UNOlogo.Visible = 'on';
            app.Background.Visible = 'on';
            app.tutorialBox.Visible = 'off';
            app.RETURNTOMENUButton.Visible = 'off';
            app.settingsBox.Visible = 'off';
            app.STANDARDButton.Visible = 'off';
            app.MINIMALISTButton.Visible = 'off';
            app.REDButton.Visible = 'off';
            app.TEALButton.Visible = 'off';
            app.PURPLEButton.Visible = 'off';
            app.GREENButton.Visible = 'off';
            
            app.UnoGameClient = UnoGameClient(app); % Open the Client Uno Game
            app.UnoGameClient.UIFigure.Visible = 'off'; % Turns the visibility of the Client Uno Game Off
        end

        % Button pushed function: RULESButton
        function RULESButtonPushed(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            app.tutorialBox.Visible = 'on';
            app.RETURNTOMENUButton.Visible = 'on';
            app.RULESButton.Visible = 'off';        % turns off rules and settings buttons (for aesthetic purposes)
            app.SETTINGSButton.Visible = 'off';
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            % start game
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio            
            app.UnoGameClient.UIFigure.Visible = 'on'; % turns on the game display
            app.UIFigure.Visible = 'off';   % turns off main window for menu/home screen
            syncGame(app.UnoGameClient);            
        end

        % Button pushed function: SETTINGSButton
        function SETTINGSButtonPushed(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            app.settingsBox.Visible = 'on';     % turns on settings box
            app.STANDARDButton.Visible = 'on';
            app.MINIMALISTButton.Visible = 'on';
            app.REDButton.Visible = 'on';
            app.TEALButton.Visible = 'on';
            app.PURPLEButton.Visible = 'on';
            app.GREENButton.Visible = 'on';
            app.RETURNTOMENUButton.Visible = 'on';
        end

        % Button pushed function: RETURNTOMENUButton
        function RETURNTOMENUButtonPushed(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            app.tutorialBox.Visible = 'off';            % goes back to menu, turns off tutorial and return buttons
            app.RETURNTOMENUButton.Visible = 'off';
            app.RULESButton.Visible = 'on';         % turns rules and settings button back on
            app.SETTINGSButton.Visible = 'on';
            app.settingsBox.Visible = 'off';        % turns settings box off
            app.STANDARDButton.Visible = 'off';
            app.MINIMALISTButton.Visible = 'off';
            app.REDButton.Visible = 'off';
            app.TEALButton.Visible = 'off';
            app.PURPLEButton.Visible = 'off';
            app.GREENButton.Visible = 'off';
        end

        % Button pushed function: GREENButton
        function GREENButtonPushed(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            app.Background.ImageSource = 'Table_2.png';
            app.UnoGameClient.Background.ImageSource = 'Table_2.png';
        end

        % Button pushed function: PURPLEButton
        function PURPLEButtonPushed(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            app.Background.ImageSource = 'Table_1.png';
            app.UnoGameClient.Background.ImageSource = 'Table_1.png';
        end

        % Button pushed function: TEALButton
        function TEALButtonPushed(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            app.Background.ImageSource = 'Table_3.png';
            app.UnoGameClient.Background.ImageSource = 'Table_3.png';
        end

        % Button pushed function: REDButton
        function REDButtonPushed(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            app.Background.ImageSource = 'Table_0.png';
            app.UnoGameClient.Background.ImageSource = 'Table_0.png';
        end

        % Button pushed function: MINIMALISTButton
        function MINIMALISTButtonPushed(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            app.UnoGameClient.deckChoice = "__";
            
            app.UnoGameClient.DrawDeck.ImageSource = 'Deck__.png';
            displayPlayerCards(app.UnoGameClient, (1 + app.UnoGameClient.PlayerHandStatus*7));
            displayOpponentCards(app.UnoGameClient);
            displayDiscardPile(app.UnoGameClient);
        end

        % Button pushed function: STANDARDButton
        function STANDARDButtonPushed(app, event)
            [y, Fs] = audioread('button.wav');   % reads the audio
            sound(y,Fs);    % plays the audio
            app.UnoGameClient.deckChoice = "_";
            
            app.UnoGameClient.DrawDeck.ImageSource = 'Deck_.png';
            displayPlayerCards(app.UnoGameClient, (1 + app.UnoGameClient.PlayerHandStatus*7));
            displayOpponentCards(app.UnoGameClient);
            displayDiscardPile(app.UnoGameClient);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = 'Table_0.png';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.UNOlogo = uiimage(app.UIFigure);
            app.UNOlogo.ImageSource = 'UNOLogo.png';
            app.RULESButton = uibutton(app.UIFigure, 'push');
            app.RULESButton.ButtonPushedFcn = createCallbackFcn(app, @RULESButtonPushed, true);
            app.RULESButton.Text = 'RULES';
            app.SETTINGSButton = uibutton(app.UIFigure, 'push');
            app.SETTINGSButton.ButtonPushedFcn = createCallbackFcn(app, @SETTINGSButtonPushed, true);
            app.SETTINGSButton.Text = 'SETTINGS';
            app.tutorialBox = uiimage(app.UIFigure);
            app.tutorialBox.ImageSource = 'rules.png';
            app.settingsBox = uiimage(app.UIFigure);
            app.settingsBox.ImageSource = 'settings.png';
            app.RETURNTOMENUButton = uibutton(app.UIFigure, 'push');
            app.RETURNTOMENUButton.ButtonPushedFcn = createCallbackFcn(app, @RETURNTOMENUButtonPushed, true);
            app.RETURNTOMENUButton.Text = 'RETURN TO MENU';
            app.STANDARDButton = uibutton(app.UIFigure, 'push');
            app.STANDARDButton.ButtonPushedFcn = createCallbackFcn(app, @STANDARDButtonPushed, true);
            app.STANDARDButton.Text = 'STANDARD';
            app.MINIMALISTButton = uibutton(app.UIFigure, 'push');
            app.MINIMALISTButton.ButtonPushedFcn = createCallbackFcn(app, @MINIMALISTButtonPushed, true);
            app.MINIMALISTButton.Text = 'MINIMALIST';
            app.REDButton = uibutton(app.UIFigure, 'push');
            app.REDButton.ButtonPushedFcn = createCallbackFcn(app, @REDButtonPushed, true);
            app.REDButton.Text = 'RED';
            app.TEALButton = uibutton(app.UIFigure, 'push');
            app.TEALButton.ButtonPushedFcn = createCallbackFcn(app, @TEALButtonPushed, true);
            app.TEALButton.Text = 'TEAL';
            app.PURPLEButton = uibutton(app.UIFigure, 'push');
            app.PURPLEButton.ButtonPushedFcn = createCallbackFcn(app, @PURPLEButtonPushed, true);
            app.PURPLEButton.Text = 'PURPLE';
            app.GREENButton = uibutton(app.UIFigure, 'push');
            app.GREENButton.ButtonPushedFcn = createCallbackFcn(app, @GREENButtonPushed, true);
            app.GREENButton.Text = 'GREEN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = home_screen_client

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end